classdef PathPlanner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes_Part                     matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        PartTab                         matlab.ui.container.Tab
        PartDecimatebyMoveLengthButton  matlab.ui.control.Button
        LengthmmEditFieldLabel          matlab.ui.control.Label
        PartLengthmmEditField           matlab.ui.control.NumericEditField
        PartCombineCollinearMovesButton  matlab.ui.control.Button
        PartStaggerStartbyPointsButton  matlab.ui.control.Button
        PartStaggerPointsEditField      matlab.ui.control.NumericEditField
        StaggerPointsEditFieldLabel     matlab.ui.control.Label
        PartUpdateTorchAnglesButton     matlab.ui.control.Button
        PartTorchAngleDropDown          matlab.ui.control.DropDown
        PartAlternateContourDirectionsButton  matlab.ui.control.Button
        PartAlternateContoursEditField  matlab.ui.control.NumericEditField
        EachContoursLabel               matlab.ui.control.Label
        PartRepairEndContinuityButton   matlab.ui.control.Button
        PartAlgorithmsLabel             matlab.ui.control.Label
        PartRepairEndContinuityButton_2  matlab.ui.control.Button
        NewXmmEditField_2               matlab.ui.control.NumericEditField
        NewXmmEditField_2Label          matlab.ui.control.Label
        NewYmmEditField_2               matlab.ui.control.NumericEditField
        NewYmmEditField_2Label          matlab.ui.control.Label
        NewZmmEditField_2               matlab.ui.control.NumericEditField
        NewZmmEditField_2Label          matlab.ui.control.Label
        SegmentRepairEndContinuityButton_2  matlab.ui.control.Button
        SegmentTab                      matlab.ui.container.Tab
        SegmentDecimatebyMoveLengthButton  matlab.ui.control.Button
        SegmentCombineCollinearMovesButton  matlab.ui.control.Button
        SegmentStaggerStartbyPointsButton  matlab.ui.control.Button
        SegmentUpdateTorchAnglesButton  matlab.ui.control.Button
        SegmentTorchAngleDropDown       matlab.ui.control.DropDown
        SegmentAlternateContourDirectionsButton  matlab.ui.control.Button
        SegmentRepairEndContinuityButton  matlab.ui.control.Button
        LengthmmEditFieldLabel_2        matlab.ui.control.Label
        SegmentLengthmmEditField        matlab.ui.control.NumericEditField
        SegmentStaggerPointsEditField   matlab.ui.control.NumericEditField
        StaggerPointsEditFieldLabel_2   matlab.ui.control.Label
        SegmentAlternateContoursEditField  matlab.ui.control.NumericEditField
        EachContoursLabel_2             matlab.ui.control.Label
        SegmentAlgorithmsLabel          matlab.ui.control.Label
        ContourTab                      matlab.ui.container.Tab
        ContourAlgorithmsLabel          matlab.ui.control.Label
        ContourDecimatebyMoveLengthButton  matlab.ui.control.Button
        ContourCombineCollinearMovesButton  matlab.ui.control.Button
        ContourStaggerStartbyPointsButton  matlab.ui.control.Button
        ContourUpdateTorchAnglesButton  matlab.ui.control.Button
        ContourTorchAngleDropDown       matlab.ui.control.DropDown
        ContourReverseContourDirectionsButton  matlab.ui.control.Button
        ContourRepairEndContinuityButton  matlab.ui.control.Button
        LengthmmEditFieldLabel_3        matlab.ui.control.Label
        ContourLengthmmEditField        matlab.ui.control.NumericEditField
        ContourStaggerPointsEditField   matlab.ui.control.NumericEditField
        StaggerPointsEditFieldLabel_3   matlab.ui.control.Label
        MoveTab                         matlab.ui.container.Tab
        MoveAlgorithmsLabel             matlab.ui.control.Label
        MoveUpdateTorchAnglesButton     matlab.ui.control.Button
        MoveTorchAngleDropDown          matlab.ui.control.DropDown
        MoveSplitMoveButton             matlab.ui.control.Button
        MoveSplitMoveEditField          matlab.ui.control.NumericEditField
        SplitRatio01Label               matlab.ui.control.Label
        MoveCombineButton               matlab.ui.control.Button
        NeighborDropDownLabel           matlab.ui.control.Label
        NeighborDropDown                matlab.ui.control.DropDown
        MoveLinspaceButton              matlab.ui.control.Button
        MoveLinspaceEditField           matlab.ui.control.NumericEditField
        MaxLengthmmLabel                matlab.ui.control.Label
        WaypointTab                     matlab.ui.container.Tab
        WaypointAlgorithmsLabel         matlab.ui.control.Label
        WaypointUpdateButton            matlab.ui.control.Button
        NewXmmEditFieldLabel            matlab.ui.control.Label
        NewXmmEditField                 matlab.ui.control.NumericEditField
        NewYmmLabel                     matlab.ui.control.Label
        NewYmmEditField                 matlab.ui.control.NumericEditField
        NewZmmLabel                     matlab.ui.control.Label
        NewZmmEditField                 matlab.ui.control.NumericEditField
        CurrentXLabel                   matlab.ui.control.Label
        CurrentYLabel                   matlab.ui.control.Label
        CurrentZLabel                   matlab.ui.control.Label
        CurrentRLabel                   matlab.ui.control.Label
        CurrentRTable                   matlab.ui.control.Table
        NewRLabel                       matlab.ui.control.Label
        R11EditField                    matlab.ui.control.NumericEditField
        R21EditField                    matlab.ui.control.NumericEditField
        R31EditField                    matlab.ui.control.NumericEditField
        R12EditField                    matlab.ui.control.NumericEditField
        R13EditField                    matlab.ui.control.NumericEditField
        R22EditField                    matlab.ui.control.NumericEditField
        R32EditField                    matlab.ui.control.NumericEditField
        R23EditField                    matlab.ui.control.NumericEditField
        R33EditField                    matlab.ui.control.NumericEditField
        CurrentSpeedLabel               matlab.ui.control.Label
        NewSpeedmmsLabel                matlab.ui.control.Label
        NewSpeedmmsEditField            matlab.ui.control.NumericEditField
        CurrentXTextField               matlab.ui.control.TextArea
        CurrentYTextField               matlab.ui.control.TextArea
        CurrentZTextField               matlab.ui.control.TextArea
        CurrentSpeedTextField           matlab.ui.control.TextArea
        UIAxes_Contour                  matlab.ui.control.UIAxes
        UIAxes_Segment                  matlab.ui.control.UIAxes
        ImportPartButton                matlab.ui.control.Button
        ExportPartButton                matlab.ui.control.Button
        ExportFileExtensionDropDownLabel  matlab.ui.control.Label
        ExportFileExtensionDropDown     matlab.ui.control.DropDown
        LogTextAreaLabel                matlab.ui.control.Label
        LogTextArea                     matlab.ui.control.TextArea
        BuildInfoTextAreaLabel          matlab.ui.control.Label
        BuildInfoTextArea               matlab.ui.control.TextArea
        ResetPartViewButton             matlab.ui.control.Button
        ResetContourViewButton          matlab.ui.control.Button
        ResetSegmentViewButton          matlab.ui.control.Button
        UNIVERSITYOFTENNESSEEARCLABORATORYLabel  matlab.ui.control.Label
        SegmentDropDownLabel            matlab.ui.control.Label
        SegmentDropDown                 matlab.ui.control.DropDown
        ContourDropDownLabel            matlab.ui.control.Label
        ContourDropDown                 matlab.ui.control.DropDown
        MoveDropDownLabel               matlab.ui.control.Label
        MoveDropDown                    matlab.ui.control.DropDown
        WaypointDropDownLabel           matlab.ui.control.Label
        WaypointDropDown                matlab.ui.control.DropDown
        PathManipulationAlgorithmsLabel  matlab.ui.control.Label
        DisplayModeDropDownLabel        matlab.ui.control.Label
        ContourDisplayModeDropDown      matlab.ui.control.DropDown
        AppLogoAxes                     matlab.ui.control.UIAxes
        MajicLogoAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        active_part;
        
        selected_segment = 1;
        selected_contour = 1;
        selected_move = 1;
        selected_waypoint = 1;
        
        n_segments = 1;
        n_contours = 1;
        n_moves = 1;
        n_waypoints = 2; % fixed
    end
    
    methods (Access = private)
        
        % Msgs
        function StartupMsg(app)
            app.LogTextArea.Value = 'Welcome to the UTK ARC Laboratory Path Planner';
            app.LogMsg('Planner version: v0.0.1');
        end%func StartupMsg
    
        function LogMsg(app, msg_string)
            app.LogTextArea.Value = [app.LogTextArea.Value;msg_string];
        end%func ErrorMsg
    
        function ErrorMsg(app,msg_string)
            error_string = strcat('[ERROR]',{' '},msg_string);
            app.LogMsg(error_string);
        end%func ErrorMsg
    
        function ActionMsg(app,msg_string)
            action_string = strcat('[ACTION]',{' '},msg_string);
            app.LogMsg(action_string);
        end%func ActionMsg
        
        function UpdateBuildStats(app)
            part_string = 'PART';
            segment_string = sprintf('SEGMENT: %i/%i',app.selected_segment,app.n_segments);
            contour_string = sprintf('CONTOUR: %i/%i',app.selected_contour,app.n_contours);
            move_string = sprintf('MOVE: %i/%i',app.selected_move,app.n_moves);
            waypoint_string = sprintf('WAYPOINT: %i/%i',app.selected_waypoint,app.n_waypoints);
            
            build_stats = app.GetBuildStats;
            part_stats_string = sprintf('Time: %1.1fsec    Length: %1.2fmm',build_stats.part_time,build_stats.part_distance);
            segment_stats_string = sprintf('Time: %1.1fsec    Length: %1.2fmm    Type: %s',build_stats.segment_time,build_stats.segment_distance,build_stats.segment_type);
            contour_stats_string = sprintf('Time: %1.1fsec    Length: %1.2fmm',build_stats.contour_time,build_stats.contour_distance);
            move_stats_string = sprintf('Length: %1.2fmm    Speed: %1.2fmm/s',build_stats.move_distance,build_stats.move_speed);
            waypoint_stats_string = sprintf('Position: %1.3fmm, %1.3fmm, %1.3fmm\nTorch ZYZ: %1.3fdeg, %1.3fdeg, %1.3fdeg',...
                build_stats.waypoint_position(1),build_stats.waypoint_position(2),build_stats.waypoint_position(3),...
                build_stats.waypoint_zyz(1),build_stats.waypoint_zyz(2),build_stats.waypoint_zyz(3));
            
            app.BuildInfoTextArea.Value = [part_string newline ...
                part_stats_string newline newline ...
                segment_string newline ...
                segment_stats_string newline newline ...
                contour_string newline ...
                contour_stats_string newline newline ...
                move_string newline ...
                move_stats_string newline newline ...
                waypoint_string newline ...
                waypoint_stats_string];
        end%func UpdateBuildStats
    
        function UpdateWaypointInformationTab(app)
            
            current_waypoint = app.GetWaypoint(app.selected_segment,app.selected_contour,app.selected_move,app.selected_waypoint);
            r = current_waypoint.R;
            tdata = r;
            set(app.CurrentRTable,'Data',tdata);
            
            build_stats = app.GetBuildStats;
            current_x_string = sprintf('%1.3fmm',build_stats.waypoint_position(1));
            current_y_string = sprintf('%1.3fmm',build_stats.waypoint_position(2));
            current_z_string = sprintf('%1.3fmm',build_stats.waypoint_position(3));
            app.CurrentXTextField.Value = [current_x_string];
            app.CurrentYTextField.Value = [current_y_string];
            app.CurrentZTextField.Value = [current_z_string];
            
            current_speed_string = sprintf('%1.2fmm/s',build_stats.move_speed);
            app.CurrentSpeedTextField.Value = [current_speed_string];
            
            app.R11EditField.Value = tdata(1,1);
            app.R21EditField.Value = tdata(2,1);
            app.R31EditField.Value = tdata(3,1);
            app.R12EditField.Value = tdata(1,2);
            app.R22EditField.Value = tdata(2,2);
            app.R32EditField.Value = tdata(3,2);
            app.R13EditField.Value = tdata(1,3);
            app.R23EditField.Value = tdata(2,3);
            app.R33EditField.Value = tdata(3,3);
            
            app.NewXmmEditField.Value = build_stats.waypoint_position(1);
            app.NewYmmEditField.Value = build_stats.waypoint_position(2);
            app.NewZmmEditField.Value = build_stats.waypoint_position(3);
            
            app.NewSpeedmmsEditField.Value = build_stats.move_speed;
            
        end%func UpdateWaypointInformationTab
    
        % Plot
        
        function UpdateAllPlots(app)
            app.UpdatePartPlot;
            app.UpdateSegmentPlot;
            app.UpdateContourPlot;
        end
        
        function UpdatePartPlot(app)
            cla(app.UIAxes_Part);
            PlotTools.PlotPartSimple(app.active_part,app.UIAxes_Part);
            PlotTools.SquareAxes3(app.UIAxes_Part);
            app.ResetPartPlot;
        end%func UpdatePartPlot
    
        function UpdateSegmentPlot(app)
            cla(app.UIAxes_Segment);
            plot_segment = app.GetSegment(app.selected_segment);
            PlotTools.PlotSegmentSimpleWithSelectedContour(plot_segment,app.selected_contour,app.UIAxes_Segment);
            PlotTools.SquareAxes3(app.UIAxes_Segment);
            app.ResetSegmentPlot;
        end%func UpdateSegmentPlot
    
        function UpdateContourPlot(app)
            cla(app.UIAxes_Contour);
            plot_contour = app.GetContour(app.selected_segment,app.selected_contour);
            switch app.ContourDisplayModeDropDown.Value
                case 'Default'
                    PlotTools.PlotContour(plot_contour,app.UIAxes_Contour);
                case 'Torch Frames'
                    PlotTools.PlotContourWithToolCoordinateFrames(plot_contour,app.UIAxes_Contour);
                case 'Simple'
                    PlotTools.PlotContourSimple(plot_contour,app.UIAxes_Contour);
                otherwise
                    app.ErrorMsg('Unknown contour plot case');
            end%switch
             
            app.ResetContourPlot;
        end%func UpdateContourPlot
    
        % Plot Formatters
        
        function ResetPartPlot(app)
            view(app.UIAxes_Part,45,45);
        end%func ResetPartPlot
    
        function ResetSegmentPlot(app)
            resetplotview(app.UIAxes_Segment,'InitializeCurrentView');
            view(app.UIAxes_Segment,45,45);
        end%func Reset Segment Plot
    
        function ResetContourPlot(app)
            resetplotview(app.UIAxes_Contour,'InitializeCurrentView');
            [x,y,z] = ContourAlgorithms.FindContourCentroid(app.GetContour(app.selected_segment,app.selected_contour));
            point = [x,y,z];
            PlotTools.SquareAxes3AboutPoint(app.UIAxes_Contour,point);
            view(app.UIAxes_Contour,45,45);
        end%func ResetContourPlot
    
        % Pseudo-getters
        
        function s = GetSegment(app,segment_number)
            s = app.active_part.segments{segment_number};
        end%func GetSegment
    
        function c = GetContour(app,segment_number,contour_number)
            s = app.GetSegment(segment_number);
            c = s.contours{contour_number};
        end%func GetContour
    
        function m = GetMove(app,segment_number,contour_number,move_number)
            c = app.GetContour(segment_number,contour_number);
            m = c.moves{move_number};
        end%func GetMove
    
        function w = GetWaypoint(app,segment_number,contour_number,move_number,waypoint_number)
            m = app.GetMove(segment_number,contour_number,move_number);
            if(waypoint_number == 1)
                w = m.point1;
            elseif(waypoint_number == 2)
                w = m.point2;
            else
                w = [];
            end%if
        end%func GetWaypoint
    
        % UI Jazz
        
        function UpdateElementCounts(app)
            app.n_segments = length(app.active_part.segments);
            
            s = app.active_part.segments{app.selected_segment};
            app.n_contours = length(s.contours);
            
            c = s.contours{app.selected_contour};
            app.n_moves = length(c.moves);
            
            app.UpdateBuildStats;
            app.UpdateWaypointInformationTab;
        end%func UpdateElementCounts
    
        function UpdateDropDowns(app)
            app.UpdateElementCounts;
            
            segment_drop_entries = (1:app.n_segments);
            app.SegmentDropDown.Items = string(segment_drop_entries');
            app.SegmentDropDown.Value = num2str(app.selected_segment);
            
            contour_drop_entries = (1:app.n_contours);
            app.ContourDropDown.Items = string(contour_drop_entries');
            app.ContourDropDown.Value = num2str(app.selected_contour);
            
            move_drop_entries = (1:app.n_moves);
            app.MoveDropDown.Items = string(move_drop_entries');
            app.MoveDropDown.Value = num2str(app.selected_move);
        end
        
        function UpdateAfterImport(app)
            app.UpdateAllPlots;
            app.UpdateDropDowns;
        end
    
        function stats = GetBuildStats(app)
            
            [part_time,part_distance,total_contours,total_moves,last_segment_contour] = app.GetPartStats(app.active_part);
            segment = app.GetSegment(app.selected_segment);
            [segment_time,segment_distance,segment_contour_count,segment_move_count] = app.GetSegmentStats(segment);
            contour = app.GetContour(app.selected_segment,app.selected_contour);
            [contour_time,contour_distance,contour_move_count] = app.GetContourStats(contour);
            
            % Get move stats
            move = app.GetMove(app.selected_segment,app.selected_contour,app.selected_move);
            move_distance = MoveAlgorithms.GetMoveDistance(move);
            p1 = move.point1;
            p2 = move.point2;
            move_speed = abs(p2.speed+p1.speed)/2;
            
            % Output
            stats.part_time = part_time;
            stats.part_distance = part_distance;
            stats.total_contours = total_contours;
            stats.total_moves = total_moves;
            stats.last_segment_contour = last_segment_contour;
            stats.segment_time = segment_time;
            stats.segment_distance = segment_distance;
            stats.segment_contour_count = segment_contour_count;
            stats.segment_move_count = segment_move_count;
            stats.segment_type = segment.segment_type;
            stats.contour_distance = contour_distance;
            stats.contour_time = contour_time;
            stats.contour_move_count = contour_move_count;
            stats.move_distance = move_distance;
            stats.move_speed = move_speed;
            if(app.selected_waypoint == 1)
                stats.waypoint_position = [p1.x,p1.y,p1.z];
                [z1,y1,z2] = Utils.GetZYZEulerAnglesFromRotationMatrix(p1.R);
                stats.waypoint_zyz = [z1,y1,z2];
            elseif(app.selected_waypoint == 2)
                stats.waypoint_position = [p2.x,p2.y,p2.z];
                [z1,y1,z2] = Utils.GetZYZEulerAnglesFromRotationMatrix(p2.R);
                stats.waypoint_zyz = [z1,y1,z2];
            else
                app.ErrorMsg('No waypoint selected');
            end%if

        end%func GetBuildStats
    
        function [part_time,part_distance,total_contours,total_moves,last_segment_contour] = GetPartStats(app,part)
            part_time = 0;
            part_distance = 0;
            total_contours = 0;
            total_moves = 0;
            last_segment_contour = cell(1,length(part.segments));
            for i = 1:length(part.segments)
                [dt,dx,segment_contour_count,segment_move_count] = app.GetSegmentStats(part.segments{i});
                last_segment_contour{i} = total_contours + segment_contour_count;
                part_time = part_time + dt;
                part_distance = part_distance + dx;
                total_contours = total_contours + segment_contour_count;
                total_moves = total_moves + segment_move_count;
            end%for i
        end%func GetPartTime
    
        function [segment_time,segment_distance,segment_contour_count,segment_move_count] = GetSegmentStats(app,segment)
            segment_time = 0;
            segment_distance = 0;
            segment_contour_count = length(segment.contours);
            segment_move_count = 0;
            for i = 1:length(segment.contours)
                [dt,dx,contour_move_count] = app.GetContourStats(segment.contours{i});
                segment_time = segment_time + dt;
                segment_distance = segment_distance + dx;
                segment_move_count = segment_move_count + contour_move_count;
            end%for i
        end%func GetSegmentTime
    
        function [contour_time,contour_distance,contour_move_count] = GetContourStats(app,contour)
            % Calculate Contour Stats
            contour_distance = 0;
            contour_time = 0;
            contour_move_count = length(contour.moves);
            for i = 1:length(contour.moves)
                move = contour.moves{i};
                p1 = move.point1;
                p2 = move.point2;
                move_speed = abs(p2.speed+p1.speed)/2;
                move_dist = MoveAlgorithms.GetMoveDistance(move);
                move_time = move_dist/move_speed;
                contour_distance = move_dist + contour_distance;
                contour_time = move_time + contour_time;
            end
        end%func GetContourTime
    end%methods

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StartupMsg;
            
            % UT LOGO
            title(app.AppLogoAxes, []);
            xlabel(app.AppLogoAxes, []);
            ylabel(app.AppLogoAxes, []);
            app.AppLogoAxes.XAxis.TickLabels = {};
            app.AppLogoAxes.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imshow('T_Slice.png', 'Parent', app.AppLogoAxes, ...
                'XData', [1 app.AppLogoAxes.Position(3)], ...
                'YData', [1 app.AppLogoAxes.Position(4)]);
            % Set limits of axes
            app.AppLogoAxes.XLim = [0 I.XData(2)];
            app.AppLogoAxes.YLim = [0 I.YData(2)];
            
            disableDefaultInteractivity(app.AppLogoAxes);
            
            % MA2JIC LOGO
            title(app.MajicLogoAxes, []);
            xlabel(app.MajicLogoAxes, []);
            ylabel(app.MajicLogoAxes, []);
            app.MajicLogoAxes.XAxis.TickLabels = {};
            app.MajicLogoAxes.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imshow('ma2jic.jpg', 'Parent', app.MajicLogoAxes, ...
                'XData', [1 app.MajicLogoAxes.Position(3)], ...
                'YData', [1 app.MajicLogoAxes.Position(4)]);
            % Set limits of axes
            app.MajicLogoAxes.XLim = [0 I.XData(2)];
            app.MajicLogoAxes.YLim = [0 I.YData(2)];
            
            disableDefaultInteractivity(app.MajicLogoAxes);
        end

        % Button pushed function: ImportPartButton
        function ImportPartButtonPushed(app, event)
            app.LogMsg('Importing part...');
            import_type = questdlg('Select import type','Import Selector','GOM','Hypermill','Cancel','Cancel');
            if strcmp(import_type,'GOM')==1
                [p,import_path] = FileTools.PromptForPartImportFromGOM;
            elseif strcmp(import_type,'Hypermill')==1
                [p,import_path] = HypermillImporter.PromptForPartImportFromHypermill;
            else
                p = 0;
                import_path = '';
            end%if
            
            if(isa(p,'Part'))
                import_string = sprintf('Imported part from %s',import_path);
                app.active_part = p;
                app.UpdateAfterImport;
            else
                import_string = 'Error: invalid part selection';
                app.active_part = [];
            end%if
            app.LogMsg(import_string);
        end

        % Value changed function: SegmentDropDown
        function SegmentDropDownValueChanged(app, event)
            app.selected_segment = str2double(app.SegmentDropDown.Value);
            app.selected_contour = 1;
            app.selected_move = 1;
            app.selected_waypoint = 1;
            
            app.UpdateDropDowns;
            app.UpdateSegmentPlot;
            app.UpdateContourPlot;
        end

        % Value changed function: ContourDropDown
        function ContourDropDownValueChanged(app, event)
            app.selected_contour = str2double(app.ContourDropDown.Value);
            app.selected_move = 1;
            app.selected_waypoint = 1;
            
            app.UpdateDropDowns;
            app.UpdateSegmentPlot;
            app.UpdateContourPlot;
        end

        % Value changed function: MoveDropDown
        function MoveDropDownValueChanged(app, event)
            app.selected_move = str2double(app.MoveDropDown.Value);
            app.selected_waypoint = 1;
            app.UpdateDropDowns;
        end

        % Value changed function: WaypointDropDown
        function WaypointDropDownValueChanged(app, event)
            app.selected_waypoint = str2double(app.WaypointDropDown.Value);
            app.UpdateDropDowns;
        end

        % Button pushed function: PartDecimatebyMoveLengthButton
        function PartDecimatebyMoveLengthButtonPushed(app, event)
            move_length = app.PartLengthmmEditField.Value;
            if(move_length < 0.1)
                app.ErrorMsg('Move Length too small!');
            end%if
            
            log_string = sprintf('PART - Decimating Part Contours by Minimum Move Length: %1.3fmm',move_length);
            app.ActionMsg(log_string);
            
            PartAlgorithms.DecimateContoursByMoveLength(app.active_part,move_length);
            
            app.UpdateDropDowns;
            app.UpdateSegmentPlot;
            app.UpdateContourPlot;
        end

        % Button pushed function: PartCombineCollinearMovesButton
        function PartCombineCollinearMovesButtonPushed(app, event)
            log_string = 'PART - Combining Collinear Moves';
            app.ActionMsg(log_string);
            
            PartAlgorithms.CombineCollinearMoves(app.active_part);
            
            app.UpdateDropDowns;
            app.UpdateSegmentPlot;
            app.UpdateContourPlot;
        end

        % Button pushed function: PartAlternateContourDirectionsButton
        function PartAlternateContourDirectionsButtonPushed(app, event)
            alternate_contours = app.PartAlternateContoursEditField.Value;
            
            log_string = sprintf('PART - Alternating Part Contours every %i Contours',alternate_contours);
            app.ActionMsg(log_string);
            
            PartAlgorithms.AlternateContourDirections(app.active_part,alternate_contours);
            
            app.UpdateDropDowns;
            app.UpdateSegmentPlot;
            app.UpdateContourPlot;
        end

        % Button pushed function: PartStaggerStartbyPointsButton
        function PartStaggerStartbyPointsButtonPushed(app, event)
            stagger_points = app.PartStaggerPointsEditField.Value;
            
            log_string = sprintf('PART - Staggering Contour starts every %i points',stagger_points);
            app.ActionMsg(log_string);

            PartAlgorithms.StaggerStartPointsByIndices(app.active_part,stagger_points);
            
            app.UpdateDropDowns;
            app.UpdateSegmentPlot;
            app.UpdateContourPlot;
        end

        % Button pushed function: PartRepairEndContinuityButton
        function PartRepairEndContinuityButtonPushed(app, event)
            log_string = 'PART - Repairing Contour End Continuity';
            app.ActionMsg(log_string);
            
            PartAlgorithms.RepairContourEndContinuity(app.active_part);
            
            app.UpdateDropDowns;
            app.UpdateAllPlots;
        end

        % Button pushed function: PartUpdateTorchAnglesButton
        function PartUpdateTorchAnglesButtonPushed(app, event)
            switch app.PartTorchAngleDropDown.Value
                case 'Inter-Contour Travel X'
                    log_string = 'PART - Updating Torch Angle Using Inter-Contour Vectors with X = Travel Vector';
                    app.ActionMsg(log_string);
            
                    PartAlgorithms.UpdateTorchQuaternionsUsingInterContourVectors(app.active_part);
                case 'Inter-Contour Fixed X'
                    log_string = 'PART - Updating Torch Angle Using Inter-Contour Vectors with X = Fixed';
                    app.ActionMsg(log_string);
            
                    PartAlgorithms.UpdateTorchAnglesUsingInterContourVectorsWithFixedTravelPlane(app.active_part,[1,0,0]);
                otherwise
                    app.ErrorMsg('PartUpdateTorchAnglesButtonPushed: Unknown case');
            end%switch
            
            app.UpdateSegmentPlot;
            app.UpdateContourPlot;
        end

        % Button pushed function: SegmentDecimatebyMoveLengthButton
        function SegmentDecimatebyMoveLengthButtonPushed(app, event)
            move_length = app.SegmentLengthmmEditField.Value;
            if(move_length < 0.1)
                app.ErrorMsg('Move Length too small!');
            end%if
            
            log_string = sprintf('SEGMENT %i - Decimating Part Contours by Minimum Move Length: %1.3fmm',app.selected_segment,move_length);
            app.ActionMsg(log_string);
            
            SegmentAlgorithms.DecimateContoursByMoveLength(app.GetSegment(app.selected_segment),move_length);
            
            app.UpdateDropDowns;
            app.UpdateSegmentPlot;
            app.UpdateContourPlot;
        end

        % Button pushed function: SegmentCombineCollinearMovesButton
        function SegmentCombineCollinearMovesButtonPushed(app, event)
            log_string = sprintf('SEGMENT %i - Combining Collinear Moves',app.selected_segment);
            app.ActionMsg(log_string);
            
            SegmentAlgorithms.CombineCollinearMoves(app.GetSegment(app.selected_segment));
            
            app.UpdateDropDowns;
            app.UpdateSegmentPlot;
            app.UpdateContourPlot;
        end

        % Button pushed function: SegmentStaggerStartbyPointsButton
        function SegmentStaggerStartbyPointsButtonPushed(app, event)
            stagger_points = app.SegmentStaggerPointsEditField.Value;
            
            log_string = sprintf('SEGMENT %i - Staggering Contour starts every %i points',app.selected_segment,stagger_points);
            app.ActionMsg(log_string);

            SegmentAlgorithms.StaggerSegmentStartPointsByIndices(app.GetSegment(app.selected_segment),stagger_points);
            
            app.UpdateDropDowns;
            app.UpdateSegmentPlot;
            app.UpdateContourPlot;
        end

        % Button pushed function: SegmentUpdateTorchAnglesButton
        function SegmentUpdateTorchAnglesButtonPushed(app, event)
            switch app.SegmentTorchAngleDropDown.Value
                case 'Inter-Contour Travel X'
                    log_string = sprintf('SEGMENT %i - Updating Torch Angle Using Inter-Contour Vectors with X = Travel Vector',app.GetSegment(app.selected_segment));
                    app.ActionMsg(log_string);
            
                    SegmentAlgorithms.UpdateTorchQuaternionsUsingInterContourVectors(app.active_part);
                case 'Inter-Contour Fixed X'
                    log_string = sprintf('SEGMENT %i - Updating Torch Angle Using Inter-Contour Vectors with X = Fixed',app.selected_segment);
                    app.ActionMsg(log_string);
            
                    SegmentAlgorithms.UpdateTorchAnglesUsingInterContourVectorsWithFixedTravelPlane(app.GetSegment(app.selected_segment));
                otherwise
                    app.ErrorMsg('SegmentUpdateTorchAnglesButtonPushed: Unknown case');
            end%switch
            
            app.UpdateSegmentPlot;
            app.UpdateContourPlot;
        end

        % Button pushed function: 
        % SegmentAlternateContourDirectionsButton
        function SegmentAlternateContourDirectionsButtonPushed(app, event)
            alternate_contours = app.SegmentAlternateContoursEditField.Value;
            
            log_string = sprintf('SEGMENT %i - Alternating Part Contours every %i Contours',app.selected_segment,alternate_contours);
            app.ActionMsg(log_string);
            
            SegmentAlgorithms.AlternateContourDirections(app.GetSegment(app.selected_segment),alternate_contours);
            
            app.UpdateDropDowns;
            app.UpdateSegmentPlot;
            app.UpdateContourPlot;
        end

        % Button pushed function: SegmentRepairEndContinuityButton
        function SegmentRepairEndContinuityButtonPushed(app, event)
            log_string = sprintf('SEGMENT %i - Repairing Contour End Continuity',app.selected_segment);
            app.ActionMsg(log_string);
            
            SegmentAlgorithms.RepairContourEndContinuity(app.GetSegment(app.selected_segment));
            
            app.UpdateDropDowns;
            app.UpdateAllPlots;
        end

        % Button pushed function: ContourDecimatebyMoveLengthButton
        function ContourDecimatebyMoveLengthButtonPushed(app, event)
            
        end

        % Button pushed function: ContourCombineCollinearMovesButton
        function ContourCombineCollinearMovesButtonPushed(app, event)
            
        end

        % Button pushed function: ContourStaggerStartbyPointsButton
        function ContourStaggerStartbyPointsButtonPushed(app, event)
            
        end

        % Button pushed function: ContourUpdateTorchAnglesButton
        function ContourUpdateTorchAnglesButtonPushed(app, event)
            
        end

        % Button pushed function: 
        % ContourReverseContourDirectionsButton
        function ContourReverseContourDirectionsButtonPushed(app, event)
            
        end

        % Button pushed function: ContourRepairEndContinuityButton
        function ContourRepairEndContinuityButtonPushed(app, event)
            
        end

        % Button pushed function: MoveUpdateTorchAnglesButton
        function MoveUpdateTorchAnglesButtonPushed(app, event)
            
        end

        % Button pushed function: MoveSplitMoveButton
        function MoveSplitMoveButtonPushed(app, event)
            
        end

        % Button pushed function: MoveCombineButton
        function MoveCombineButtonPushed(app, event)
            
        end

        % Button pushed function: WaypointUpdateButton
        function WaypointUpdateButtonPushed(app, event)
            app.ErrorMsg('Update Waypoint not implemented!');
            waypoint = app.GetWaypoint(app.selected_segment,app.selected_contour,app.selected_move,app.selected_waypoint);
            
            new_x = app.NewXmmEditField.Value;
            new_y = app.NewYmmEditField.Value;
            new_z = app.NewZmmEditField.Value;
            
            new_R = [0,0,0;0,0,0;0,0,0];
            new_R(1,1) = app.R11EditField.Value;
            new_R(2,1) = app.R21EditField.Value;
            new_R(3,1) = app.R31EditField.Value;
            new_R(1,2) = app.R12EditField.Value;
            new_R(2,2) = app.R22EditField.Value;
            new_R(3,2) = app.R32EditField.Value;
            new_R(1,3) = app.R13EditField.Value;
            new_R(2,3) = app.R23EditField.Value;
            new_R(3,3) = app.R33EditField.Value;
            
            new_speed = app.NewSpeedmmsEditField.Value;
            
            WaypointAlgorithms.UpdateWaypointPosition(waypoint,new_x,new_y,new_z);
            
            WaypointAlgorithms.UpdateWaypointRotation(waypoint,new_R);
            
            WaypointAlgorithms.UpdateWaypointSpeed(waypoint,new_speed);
            
            app.UpdateAllPlots();
            app.UpdateElementCounts;
        end

        % Button pushed function: ExportPartButton
        function ExportPartButtonPushed(app, event)
            questdlg('Please select a save destination.','INFO','OK','OK');
            dir = uigetdir;

            if(~dir)
                app.ErrorMsg('No directory selected');
                return;
            end%if
            
            text = inputdlg('Enter file name');
            
            if(~strcmp(text,''))
                app.ActionMsg('Exporting file...');
                file_path = strcat(dir,'\',text,'.path');
                file_path = file_path{1};
                
                log_string = app.LogTextArea.Value;
                log_file_path = strcat(dir,'\',text,'[log]','.txt');
                log_file_path = log_file_path{1};
                
                build_stats = app.GetBuildStats;
                part_time_delays = build_stats.part_time+((build_stats.total_contours-1).*30);
                
                %Making Build Stats String
                part_stats_string = sprintf('Time: %1.1fsec\nEstimated Time with Delays: %1.1fsec\nLength: %1.2fmm\nContours: %1.0f\nMoves: %1.0f\nLast Layer of Each Segment: ', ...
                                             build_stats.part_time,part_time_delays,build_stats.part_distance,build_stats.total_contours,build_stats.total_moves);                         
                last_layer_string = sprintf('|%d',build_stats.last_segment_contour{:});
                part_stats_string = strcat(part_stats_string,last_layer_string,'|');
                
                PathFileWriter.WritePart(file_path,app.active_part);
                LogFileWriter.WriteLog(log_file_path,file_path,log_string,part_stats_string,app.active_part);
            else
               app.ErrorMsg('No file name given.');
               return;
            end
        end

        % Value changed function: ContourDisplayModeDropDown
        function ContourDisplayModeDropDownValueChanged(app, event)
            app.UpdateContourPlot;
        end

        % Button pushed function: PartRepairEndContinuityButton_2
        function MovePartButton(app, event)
            new_x = app.NewXmmEditField_2.Value;
            new_y = app.NewYmmEditField_2.Value;
            new_z = app.NewZmmEditField_2.Value;
            
            PartAlgorithms.TranslatePartToPoint(app.active_part,new_x,new_y,new_z);
            app.UpdateAllPlots();
        end

        % Button pushed function: SegmentRepairEndContinuityButton_2
        function SegmentManifestButton(app, event)
            popout = PopoutFigure; 
            uiwait(popout.parent_figure); 
            %app.UserInputEditField.Value = popout.value; 
        end

        % Button pushed function: MoveLinspaceButton
        function MoveLinspaceButtonPushed(app, event)
            current_contour = app.GetContour(app.selected_segment,app.selected_contour);
            max_move_length = app.MoveLinspaceEditField.Value;
            ContourAlgorithms.LinspaceMoveByDistance(current_contour,app.selected_move,max_move_length);
            app.UpdateAllPlots();
        end

        % Button pushed function: ResetContourViewButton
        function ResetContourViewButtonPushed(app, event)
            app.ResetContourPlot();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes_Part = uiaxes(app.UIFigure);
            title(app.UIAxes_Part, 'Part Preview')
            xlabel(app.UIAxes_Part, 'X')
            ylabel(app.UIAxes_Part, 'Y')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PartTab = uitab(app.TabGroup);
            app.PartTab.Title = 'Part';
            app.PartDecimatebyMoveLengthButton = uibutton(app.PartTab, 'push');
            app.PartDecimatebyMoveLengthButton.ButtonPushedFcn = createCallbackFcn(app, @PartDecimatebyMoveLengthButtonPushed, true);
            app.PartDecimatebyMoveLengthButton.Text = 'Decimate by Move Length';
            app.LengthmmEditFieldLabel = uilabel(app.PartTab);
            app.LengthmmEditFieldLabel.Text = 'Length (mm)';
            app.PartLengthmmEditField = uieditfield(app.PartTab, 'numeric');
            app.PartLengthmmEditField.Limits = [0.1 100];
            app.PartLengthmmEditField.Value = 1;
            app.PartCombineCollinearMovesButton = uibutton(app.PartTab, 'push');
            app.PartCombineCollinearMovesButton.ButtonPushedFcn = createCallbackFcn(app, @PartCombineCollinearMovesButtonPushed, true);
            app.PartCombineCollinearMovesButton.Text = 'Combine Collinear Moves';
            app.PartStaggerStartbyPointsButton = uibutton(app.PartTab, 'push');
            app.PartStaggerStartbyPointsButton.ButtonPushedFcn = createCallbackFcn(app, @PartStaggerStartbyPointsButtonPushed, true);
            app.PartStaggerStartbyPointsButton.Text = 'Stagger Start by Points';
            app.PartStaggerPointsEditField = uieditfield(app.PartTab, 'numeric');
            app.PartStaggerPointsEditField.Limits = [1 Inf];
            app.PartStaggerPointsEditField.RoundFractionalValues = 'on';
            app.PartStaggerPointsEditField.ValueDisplayFormat = '%11.g';
            app.PartStaggerPointsEditField.Value = 1;
            app.StaggerPointsEditFieldLabel = uilabel(app.PartTab);
            app.StaggerPointsEditFieldLabel.Text = 'Stagger Points';
            app.PartUpdateTorchAnglesButton = uibutton(app.PartTab, 'push');
            app.PartUpdateTorchAnglesButton.ButtonPushedFcn = createCallbackFcn(app, @PartUpdateTorchAnglesButtonPushed, true);
            app.PartUpdateTorchAnglesButton.Text = 'Update Torch Angles';
            app.PartTorchAngleDropDown = uidropdown(app.PartTab);
            app.PartTorchAngleDropDown.Items = {'Inter-Contour Travel X', 'Inter-Contour Fixed X'};
            app.PartTorchAngleDropDown.Value = 'Inter-Contour Travel X';
            app.PartAlternateContourDirectionsButton = uibutton(app.PartTab, 'push');
            app.PartAlternateContourDirectionsButton.ButtonPushedFcn = createCallbackFcn(app, @PartAlternateContourDirectionsButtonPushed, true);
            app.PartAlternateContourDirectionsButton.Text = 'Alternate Contour Directions';
            app.PartAlternateContoursEditField = uieditfield(app.PartTab, 'numeric');
            app.PartAlternateContoursEditField.Limits = [1 Inf];
            app.PartAlternateContoursEditField.RoundFractionalValues = 'on';
            app.PartAlternateContoursEditField.ValueDisplayFormat = '%11g';
            app.PartAlternateContoursEditField.Value = 1;
            app.EachContoursLabel = uilabel(app.PartTab);
            app.EachContoursLabel.Text = 'Each # Contours';
            app.PartRepairEndContinuityButton = uibutton(app.PartTab, 'push');
            app.PartRepairEndContinuityButton.ButtonPushedFcn = createCallbackFcn(app, @PartRepairEndContinuityButtonPushed, true);
            app.PartRepairEndContinuityButton.Text = 'Repair End Continuity';
            app.PartAlgorithmsLabel = uilabel(app.PartTab);
            app.PartAlgorithmsLabel.Text = 'Part Algorithms';
            app.PartRepairEndContinuityButton_2 = uibutton(app.PartTab, 'push');
            app.PartRepairEndContinuityButton_2.ButtonPushedFcn = createCallbackFcn(app, @MovePartButton, true);
            app.PartRepairEndContinuityButton_2.Text = 'Center Part on Point';
            app.NewXmmEditField_2 = uieditfield(app.PartTab, 'numeric');
            app.NewXmmEditField_2Label = uilabel(app.PartTab);
            app.NewXmmEditField_2Label.Text = 'New X (mm)';
            app.NewYmmEditField_2 = uieditfield(app.PartTab, 'numeric');
            app.NewYmmEditField_2Label = uilabel(app.PartTab);
            app.NewYmmEditField_2Label.Text = 'New Y (mm)';
            app.NewZmmEditField_2 = uieditfield(app.PartTab, 'numeric');
            app.NewZmmEditField_2Label = uilabel(app.PartTab);
            app.NewZmmEditField_2Label.Text = 'New Z (mm)';
            app.SegmentRepairEndContinuityButton_2 = uibutton(app.PartTab, 'push');
            app.SegmentRepairEndContinuityButton_2.ButtonPushedFcn = createCallbackFcn(app, @SegmentManifestButton, true);
            app.SegmentRepairEndContinuityButton_2.Text = 'Segment Manifest';
            app.SegmentTab = uitab(app.TabGroup);
            app.SegmentTab.Title = 'Segment';
            app.SegmentDecimatebyMoveLengthButton = uibutton(app.SegmentTab, 'push');
            app.SegmentDecimatebyMoveLengthButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentDecimatebyMoveLengthButtonPushed, true);
            app.SegmentDecimatebyMoveLengthButton.Text = 'Decimate by Move Length';
            app.SegmentCombineCollinearMovesButton = uibutton(app.SegmentTab, 'push');
            app.SegmentCombineCollinearMovesButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentCombineCollinearMovesButtonPushed, true);
            app.SegmentCombineCollinearMovesButton.Text = 'Combine Collinear Moves';
            app.SegmentStaggerStartbyPointsButton = uibutton(app.SegmentTab, 'push');
            app.SegmentStaggerStartbyPointsButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentStaggerStartbyPointsButtonPushed, true);
            app.SegmentStaggerStartbyPointsButton.Text = 'Stagger Start by Points';
            app.SegmentUpdateTorchAnglesButton = uibutton(app.SegmentTab, 'push');
            app.SegmentUpdateTorchAnglesButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentUpdateTorchAnglesButtonPushed, true);
            app.SegmentUpdateTorchAnglesButton.Text = 'Update Torch Angles';
            app.SegmentTorchAngleDropDown = uidropdown(app.SegmentTab);
            app.SegmentTorchAngleDropDown.Items = {'Inter-Contour Travel X', 'Inter-Contour Fixed X'};
            app.SegmentTorchAngleDropDown.Value = 'Inter-Contour Travel X';
            app.SegmentAlternateContourDirectionsButton = uibutton(app.SegmentTab, 'push');
            app.SegmentAlternateContourDirectionsButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentAlternateContourDirectionsButtonPushed, true);
            app.SegmentAlternateContourDirectionsButton.Text = 'Alternate Contour Directions';
            app.SegmentRepairEndContinuityButton = uibutton(app.SegmentTab, 'push');
            app.SegmentRepairEndContinuityButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentRepairEndContinuityButtonPushed, true);
            app.SegmentRepairEndContinuityButton.Text = 'Repair End Continuity';
            app.LengthmmEditFieldLabel_2 = uilabel(app.SegmentTab);
            app.LengthmmEditFieldLabel_2.Text = 'Length (mm)';
            app.SegmentLengthmmEditField = uieditfield(app.SegmentTab, 'numeric');
            app.SegmentLengthmmEditField.Limits = [0.1 100];
            app.SegmentLengthmmEditField.Value = 1;
            app.SegmentStaggerPointsEditField = uieditfield(app.SegmentTab, 'numeric');
            app.SegmentStaggerPointsEditField.Limits = [1 Inf];
            app.SegmentStaggerPointsEditField.RoundFractionalValues = 'on';
            app.SegmentStaggerPointsEditField.ValueDisplayFormat = '%11.g';
            app.SegmentStaggerPointsEditField.Value = 1;
            app.StaggerPointsEditFieldLabel_2 = uilabel(app.SegmentTab);
            app.StaggerPointsEditFieldLabel_2.Text = 'Stagger Points';
            app.SegmentAlternateContoursEditField = uieditfield(app.SegmentTab, 'numeric');
            app.SegmentAlternateContoursEditField.Limits = [1 Inf];
            app.SegmentAlternateContoursEditField.RoundFractionalValues = 'on';
            app.SegmentAlternateContoursEditField.ValueDisplayFormat = '%11g';
            app.SegmentAlternateContoursEditField.Value = 1;
            app.EachContoursLabel_2 = uilabel(app.SegmentTab);
            app.EachContoursLabel_2.Text = 'Each # Contours';
            app.SegmentAlgorithmsLabel = uilabel(app.SegmentTab);
            app.SegmentAlgorithmsLabel.Text = 'Segment Algorithms';
            app.ContourTab = uitab(app.TabGroup);
            app.ContourTab.Title = 'Contour';
            app.ContourAlgorithmsLabel = uilabel(app.ContourTab);
            app.ContourAlgorithmsLabel.Text = 'Contour Algorithms';
            app.ContourDecimatebyMoveLengthButton = uibutton(app.ContourTab, 'push');
            app.ContourDecimatebyMoveLengthButton.ButtonPushedFcn = createCallbackFcn(app, @ContourDecimatebyMoveLengthButtonPushed, true);
            app.ContourDecimatebyMoveLengthButton.Text = 'Decimate by Move Length';
            app.ContourCombineCollinearMovesButton = uibutton(app.ContourTab, 'push');
            app.ContourCombineCollinearMovesButton.ButtonPushedFcn = createCallbackFcn(app, @ContourCombineCollinearMovesButtonPushed, true);
            app.ContourCombineCollinearMovesButton.Text = 'Combine Collinear Moves';
            app.ContourStaggerStartbyPointsButton = uibutton(app.ContourTab, 'push');
            app.ContourStaggerStartbyPointsButton.ButtonPushedFcn = createCallbackFcn(app, @ContourStaggerStartbyPointsButtonPushed, true);
            app.ContourStaggerStartbyPointsButton.Enable = 'off';
            app.ContourStaggerStartbyPointsButton.Text = 'Stagger Start by Points';
            app.ContourUpdateTorchAnglesButton = uibutton(app.ContourTab, 'push');
            app.ContourUpdateTorchAnglesButton.ButtonPushedFcn = createCallbackFcn(app, @ContourUpdateTorchAnglesButtonPushed, true);
            app.ContourUpdateTorchAnglesButton.Text = 'Update Torch Angles';
            app.ContourTorchAngleDropDown = uidropdown(app.ContourTab);
            app.ContourTorchAngleDropDown.Items = {'Inter-Contour Travel X', 'Inter-Contour Fixed X'};
            app.ContourTorchAngleDropDown.Value = 'Inter-Contour Travel X';
            app.ContourReverseContourDirectionsButton = uibutton(app.ContourTab, 'push');
            app.ContourReverseContourDirectionsButton.ButtonPushedFcn = createCallbackFcn(app, @ContourReverseContourDirectionsButtonPushed, true);
            app.ContourReverseContourDirectionsButton.Text = 'Reverse Contour Direction';
            app.ContourRepairEndContinuityButton = uibutton(app.ContourTab, 'push');
            app.ContourRepairEndContinuityButton.ButtonPushedFcn = createCallbackFcn(app, @ContourRepairEndContinuityButtonPushed, true);
            app.ContourRepairEndContinuityButton.Text = 'Repair End Continuity';
            app.LengthmmEditFieldLabel_3 = uilabel(app.ContourTab);
            app.LengthmmEditFieldLabel_3.Text = 'Length (mm)';
            app.ContourLengthmmEditField = uieditfield(app.ContourTab, 'numeric');
            app.ContourLengthmmEditField.Limits = [0.1 100];
            app.ContourLengthmmEditField.Value = 1;
            app.ContourStaggerPointsEditField = uieditfield(app.ContourTab, 'numeric');
            app.ContourStaggerPointsEditField.Limits = [1 Inf];
            app.ContourStaggerPointsEditField.RoundFractionalValues = 'on';
            app.ContourStaggerPointsEditField.ValueDisplayFormat = '%11.g';
            app.ContourStaggerPointsEditField.Value = 1;
            app.StaggerPointsEditFieldLabel_3 = uilabel(app.ContourTab);
            app.StaggerPointsEditFieldLabel_3.Text = 'Stagger Points';
            app.MoveTab = uitab(app.TabGroup);
            app.MoveTab.Title = 'Move';
            app.MoveAlgorithmsLabel = uilabel(app.MoveTab);
            app.MoveAlgorithmsLabel.Text = 'Move Algorithms';
            app.MoveUpdateTorchAnglesButton = uibutton(app.MoveTab, 'push');
            app.MoveUpdateTorchAnglesButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpdateTorchAnglesButtonPushed, true);
            app.MoveUpdateTorchAnglesButton.Text = 'Update Torch Angles';
            app.MoveTorchAngleDropDown = uidropdown(app.MoveTab);
            app.MoveTorchAngleDropDown.Items = {'Inter-Contour Travel X', 'Inter-Contour Fixed X'};
            app.MoveTorchAngleDropDown.Value = 'Inter-Contour Travel X';
            app.MoveSplitMoveButton = uibutton(app.MoveTab, 'push');
            app.MoveSplitMoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveSplitMoveButtonPushed, true);
            app.MoveSplitMoveButton.Text = 'Split Move';
            app.MoveSplitMoveEditField = uieditfield(app.MoveTab, 'numeric');
            app.MoveSplitMoveEditField.Limits = [1 Inf];
            app.MoveSplitMoveEditField.RoundFractionalValues = 'on';
            app.MoveSplitMoveEditField.ValueDisplayFormat = '%11.g';
            app.MoveSplitMoveEditField.Value = 1;
            app.SplitRatio01Label = uilabel(app.MoveTab);
            app.SplitRatio01Label.Text = 'Split Ratio (0-1)';
            app.MoveCombineButton = uibutton(app.MoveTab, 'push');
            app.MoveCombineButton.ButtonPushedFcn = createCallbackFcn(app, @MoveCombineButtonPushed, true);
            app.MoveCombineButton.Text = 'Combine with Move';
            app.NeighborDropDownLabel = uilabel(app.MoveTab);
            app.NeighborDropDownLabel.Text = 'Neighbor';
            app.NeighborDropDown = uidropdown(app.MoveTab);
            app.NeighborDropDown.Items = {'Next', 'Previous'};
            app.NeighborDropDown.Value = 'Previous';
            app.MoveLinspaceButton = uibutton(app.MoveTab, 'push');
            app.MoveLinspaceButton.ButtonPushedFcn = createCallbackFcn(app, @MoveLinspaceButtonPushed, true);
            app.MoveLinspaceButton.Text = 'Move Linspace';
            app.MoveLinspaceEditField = uieditfield(app.MoveTab, 'numeric');
            app.MoveLinspaceEditField.Limits = [1 Inf];
            app.MoveLinspaceEditField.RoundFractionalValues = 'on';
            app.MoveLinspaceEditField.ValueDisplayFormat = '%11.g';
            app.MoveLinspaceEditField.Value = 1;
            app.MaxLengthmmLabel = uilabel(app.MoveTab);
            app.MaxLengthmmLabel.Text = 'Max Length (mm)';
            app.WaypointTab = uitab(app.TabGroup);
            app.WaypointTab.Title = 'Waypoint';
            app.WaypointAlgorithmsLabel = uilabel(app.WaypointTab);
            app.WaypointAlgorithmsLabel.Text = 'Waypoint Algorithms';
            app.WaypointUpdateButton = uibutton(app.WaypointTab, 'push');
            app.WaypointUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @WaypointUpdateButtonPushed, true);
            app.WaypointUpdateButton.Text = 'Update Waypoint';
            app.NewXmmEditFieldLabel = uilabel(app.WaypointTab);
            app.NewXmmEditFieldLabel.Text = 'New X (mm)';
            app.NewXmmEditField = uieditfield(app.WaypointTab, 'numeric');
            app.NewYmmLabel = uilabel(app.WaypointTab);
            app.NewYmmLabel.Text = 'New Y (mm)';
            app.NewYmmEditField = uieditfield(app.WaypointTab, 'numeric');
            app.NewZmmLabel = uilabel(app.WaypointTab);
            app.NewZmmLabel.Text = 'New Z (mm)';
            app.NewZmmEditField = uieditfield(app.WaypointTab, 'numeric');
            app.CurrentXLabel = uilabel(app.WaypointTab);
            app.CurrentXLabel.Text = 'Current X: ';
            app.CurrentYLabel = uilabel(app.WaypointTab);
            app.CurrentYLabel.Text = 'Current Y: ';
            app.CurrentZLabel = uilabel(app.WaypointTab);
            app.CurrentZLabel.Text = 'Current Z: ';
            app.CurrentRLabel = uilabel(app.WaypointTab);
            app.CurrentRLabel.Text = 'Current R: ';
            app.CurrentRTable = uitable(app.WaypointTab);
            app.CurrentRTable.ColumnName = {''};
            app.CurrentRTable.RowName = {};
            app.NewRLabel = uilabel(app.WaypointTab);
            app.NewRLabel.Text = 'New R:';
            app.R11EditField = uieditfield(app.WaypointTab, 'numeric');
            app.R21EditField = uieditfield(app.WaypointTab, 'numeric');
            app.R31EditField = uieditfield(app.WaypointTab, 'numeric');
            app.R12EditField = uieditfield(app.WaypointTab, 'numeric');
            app.R13EditField = uieditfield(app.WaypointTab, 'numeric');
            app.R22EditField = uieditfield(app.WaypointTab, 'numeric');
            app.R32EditField = uieditfield(app.WaypointTab, 'numeric');
            app.R23EditField = uieditfield(app.WaypointTab, 'numeric');
            app.R33EditField = uieditfield(app.WaypointTab, 'numeric');
            app.CurrentSpeedLabel = uilabel(app.WaypointTab);
            app.CurrentSpeedLabel.Text = 'Current Speed: ';
            app.NewSpeedmmsLabel = uilabel(app.WaypointTab);
            app.NewSpeedmmsLabel.Text = 'New Speed (mm/s)';
            app.NewSpeedmmsEditField = uieditfield(app.WaypointTab, 'numeric');
            app.CurrentXTextField = uitextarea(app.WaypointTab);
            app.CurrentYTextField = uitextarea(app.WaypointTab);
            app.CurrentZTextField = uitextarea(app.WaypointTab);
            app.CurrentSpeedTextField = uitextarea(app.WaypointTab);
            app.UIAxes_Contour = uiaxes(app.UIFigure);
            title(app.UIAxes_Contour, 'Current Contour')
            xlabel(app.UIAxes_Contour, 'X')
            ylabel(app.UIAxes_Contour, 'Y')
            app.UIAxes_Segment = uiaxes(app.UIFigure);
            title(app.UIAxes_Segment, 'Segment Preview')
            xlabel(app.UIAxes_Segment, 'X')
            ylabel(app.UIAxes_Segment, 'Y')
            app.ImportPartButton = uibutton(app.UIFigure, 'push');
            app.ImportPartButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPartButtonPushed, true);
            app.ImportPartButton.Text = 'Import Part';
            app.ExportPartButton = uibutton(app.UIFigure, 'push');
            app.ExportPartButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPartButtonPushed, true);
            app.ExportPartButton.Text = 'Export Part';
            app.ExportFileExtensionDropDownLabel = uilabel(app.UIFigure);
            app.ExportFileExtensionDropDownLabel.Text = 'Export File Extension';
            app.ExportFileExtensionDropDown = uidropdown(app.UIFigure);
            app.ExportFileExtensionDropDown.Items = {'.path'};
            app.ExportFileExtensionDropDown.Value = '.path';
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.BuildInfoTextAreaLabel = uilabel(app.UIFigure);
            app.BuildInfoTextAreaLabel.Text = 'Build Info';
            app.BuildInfoTextArea = uitextarea(app.UIFigure);
            app.ResetPartViewButton = uibutton(app.UIFigure, 'push');
            app.ResetPartViewButton.Text = 'Reset Part View';
            app.ResetContourViewButton = uibutton(app.UIFigure, 'push');
            app.ResetContourViewButton.ButtonPushedFcn = createCallbackFcn(app, @ResetContourViewButtonPushed, true);
            app.ResetContourViewButton.Text = 'Reset Contour View';
            app.ResetSegmentViewButton = uibutton(app.UIFigure, 'push');
            app.ResetSegmentViewButton.Text = 'Reset Segment View';
            app.UNIVERSITYOFTENNESSEEARCLABORATORYLabel = uilabel(app.UIFigure);
            app.UNIVERSITYOFTENNESSEEARCLABORATORYLabel.Text = {'UNIVERSITY OF TENNESSEE ARC LABORATORY'; 'LSAMM PATH PLANNING SOFTWARE'};
            app.SegmentDropDownLabel = uilabel(app.UIFigure);
            app.SegmentDropDownLabel.Text = 'Segment';
            app.SegmentDropDown = uidropdown(app.UIFigure);
            app.SegmentDropDown.Items = {'1'};
            app.SegmentDropDown.ValueChangedFcn = createCallbackFcn(app, @SegmentDropDownValueChanged, true);
            app.SegmentDropDown.Value = '1';
            app.ContourDropDownLabel = uilabel(app.UIFigure);
            app.ContourDropDownLabel.Text = 'Contour';
            app.ContourDropDown = uidropdown(app.UIFigure);
            app.ContourDropDown.Items = {'1'};
            app.ContourDropDown.ValueChangedFcn = createCallbackFcn(app, @ContourDropDownValueChanged, true);
            app.ContourDropDown.Value = '1';
            app.MoveDropDownLabel = uilabel(app.UIFigure);
            app.MoveDropDownLabel.Text = 'Move';
            app.MoveDropDown = uidropdown(app.UIFigure);
            app.MoveDropDown.Items = {'1'};
            app.MoveDropDown.ValueChangedFcn = createCallbackFcn(app, @MoveDropDownValueChanged, true);
            app.MoveDropDown.Value = '1';
            app.WaypointDropDownLabel = uilabel(app.UIFigure);
            app.WaypointDropDownLabel.Text = 'Waypoint';
            app.WaypointDropDown = uidropdown(app.UIFigure);
            app.WaypointDropDown.Items = {'1', '2'};
            app.WaypointDropDown.ValueChangedFcn = createCallbackFcn(app, @WaypointDropDownValueChanged, true);
            app.WaypointDropDown.Value = '1';
            app.PathManipulationAlgorithmsLabel = uilabel(app.UIFigure);
            app.PathManipulationAlgorithmsLabel.Text = 'Path Manipulation Algorithms';
            app.DisplayModeDropDownLabel = uilabel(app.UIFigure);
            app.DisplayModeDropDownLabel.Text = 'Display Mode';
            app.ContourDisplayModeDropDown = uidropdown(app.UIFigure);
            app.ContourDisplayModeDropDown.Items = {'Default', 'Torch Frames', 'Simple'};
            app.ContourDisplayModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ContourDisplayModeDropDownValueChanged, true);
            app.ContourDisplayModeDropDown.Value = 'Default';
            app.AppLogoAxes = uiaxes(app.UIFigure);
            app.MajicLogoAxes = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = PathPlanner

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end